classdef RecordingFileBrowser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        YscaleSliderLabel         matlab.ui.control.Label
        YscaleSlider              matlab.ui.control.Slider
        Panel                     matlab.ui.container.Panel
        ElectrodesLabel           matlab.ui.control.Label
        ElectrodesEditField       matlab.ui.control.EditField
        InstancesEditFieldLabel   matlab.ui.control.Label
        InstancesEditField        matlab.ui.control.EditField
        MetricsDropDownLabel      matlab.ui.control.Label
        MetricsDropDown           matlab.ui.control.DropDown
        ConditionsEditFieldLabel  matlab.ui.control.Label
        ConditionsEditField       matlab.ui.control.EditField
        UIAxes2                   matlab.ui.control.UIAxes
        TW1SliderLabel            matlab.ui.control.Label
        TW1Slider                 matlab.ui.control.Slider
        TW2SliderLabel            matlab.ui.control.Label
        TW2Slider                 matlab.ui.control.Slider
        FilenameLabel             matlab.ui.control.Label
        FilenameEditField         matlab.ui.control.EditField
        BrowseButton              matlab.ui.control.Button
        LoadButton                matlab.ui.control.Button
        UpdateplotButton          matlab.ui.control.Button
    end


    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            
            if ispc
                slash = '\';
            else
                slash = '/';
            end
            savepath = './filefullpath.mat';
            if exist(savepath,'file')
                load(savepath)
            end
            searchPath = app.FilenameEditField.Value;
            idx = find(searchPath == slash,1, 'last');
            searchPath = searchPath(1:idx);
            [filename,pathname] = uigetfile(searchPath);
            filefullpath = [pathname,filename];
            app.FilenameEditField.Value = filefullpath;
            save('./filefullpath.mat','filefullpath')
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %             global data;
            filefullpath = app.FilenameEditField.Value;
            if exist(filefullpath,'file') == 2
                load(filefullpath)
            else
                app.BrowseButton.ButtonPushedFcn(app,event);
                filefullpath = app.FilenameEditField.Value;
                load(filefullpath)
            end
            if ispc
                slash = '\';
            else
                slash = '/';
            end
            slashidx = find(filefullpath == slash);
            NumInstances = numel(instanceinfo);
            thisInstance = 1;
            EID = 1;
            % update instance values
            app.InstancesEditField.Value = ['1:',num2str(NumInstances)];
            % update electrode values
            app.ElectrodesEditField.Value = '1:128';
            % conditions
            conditions = instanceinfo(thisInstance).trialInfo.ConditionID;
            UniCondID = unique(conditions);
            app.ConditionsEditField.Value = [num2str(min(UniCondID)),':',num2str(max(UniCondID))];
            % load one electrode and get the metrics
            fieldIdx = zeros(3,1);
            fields = [{'MUAe'},{'LFP'},{'PSTH'}];
            if isfield(instanceinfo(1),'electrodeArtifactRemovedNeuralDataPath')
                ARdata = 1;
                idx = cell(2,1);
                idx{1} = find(instanceinfo(thisInstance).electrodeArtifactRemovedNeuralDataPath{EID} == '/');
                idx{2} = find(instanceinfo(thisInstance).electrodeArtifactRemovedNeuralDataPath{EID} == '\');
                slash2 = '/\';
                slash2 = slash2(2-double(isempty(idx{2})));
                idx = find(instanceinfo(thisInstance).electrodeArtifactRemovedNeuralDataPath{EID} == slash2,1,'first');
                fullpath = [filefullpath(1:slashidx(end-1)),instanceinfo(1).electrodeArtifactRemovedNeuralDataPath{EID}(idx:end)];
                load(fullpath);
                
                if isfield(electrodeArtifactRemovedNeuralData,'MUAe')
                    fieldIdx(1) = 1;
                end
                if isfield(electrodeArtifactRemovedNeuralData,'LFP')
                    fieldIdx(2) = 1;
                end
                if isfield(electrodeArtifactRemovedNeuralData,'PSTH')
                    fieldIdx(3) = 1;
                end
            elseif isfield(instanceinfo(1),'electrodeNeuraldataPath')
                ARdata = 0;
                idx = cell(2,1);
                idx{1} = find(instanceinfo(thisInstance).electrodeNeuraldataPath{EID} == '/');
                idx{2} = find(instanceinfo(thisInstance).electrodeNeuraldataPath{EID} == '\');
                slash2 = '/\';
                slash2 = slash2(2-double(isempty(idx{2})));
                idx = find(instanceinfo(thisInstance).electrodeNeuraldataPath{EID} == slash2,1,'first');
                fullpath = [filefullpath(1:slashidx(end-1)),instanceinfo(1).electrodeNeuraldataPath{EID}(idx:end)];
                load(fullpath);
                
                if isfield(electrodeNeuraldata,'MUAe')
                    fieldIdx(1) = 1;
                end
                if isfield(electrodeNeuraldata,'LFP')
                    fieldIdx(2) = 1;
                end
                if isfield(electrodeNeuraldata,'PSTH')
                    fieldIdx(3) = 1;
                end
            end
            app.MetricsDropDown.Items = fields(logical(fieldIdx));
            
            % trial information
            cmd = ['conditions = ', app.ConditionsEditField.Value,';'];
            eval(cmd);
            trialParam = instanceinfo(thisInstance).param.trialParam;
            cmd = ['metricParam = instanceinfo(thisInstance).param.', app.MetricsDropDown.Value, 'parameters',';'];
            eval(cmd);
            
            NumMetrics = sum(fieldIdx);
            % Calculate data
            for thisMetrics = 1:NumMetrics
                switch thisMetrics
                    case 1
                        MetricsName = 'MUAe';
                    case 2
                        MetricsName = 'LFP';
                    case 3
                        MetricsName = 'PSTH';
                end
                
                
                % data size and resolution
                if ARdata
                    cmd = ['tmpdata = electrodeArtifactRemovedNeuralData.', MetricsName,';'];
                    eval(cmd);
                else
                    cmd = ['tmpdata = electrodeNeuraldataPath.', MetricsName,';'];
                    eval(cmd);
                end
                
                datalength = size(tmpdata.data,1);


                % calculate neural response
                electrodes = 1:128;
            %                 ydata = zeros(datalength,numel(conditions),numel(electrodes),numel(instanceinfo));
                ydata = cell(numel(conditions),1);
                xdata = linspace(trialParam.startT,trialParam.endT,datalength);
                
                % calculate the average
                for thisInstance = 1:numel(instanceinfo)
                    for thisElec = 1:numel(electrodes)
                        % load electrode data
                        if ARdata
                            idx = cell(2,1);
                            idx{1} = find(instanceinfo(thisInstance).electrodeArtifactRemovedNeuralDataPath{thisElec} == '/');
                            idx{2} = find(instanceinfo(thisInstance).electrodeArtifactRemovedNeuralDataPath{thisElec} == '\');
                            slash2 = '/\';
                            slash2 = slash2(2-double(isempty(idx{2})));
                            idx = find(instanceinfo(thisInstance).electrodeArtifactRemovedNeuralDataPath{thisElec} == slash2,1,'first');
                            fullpath = [filefullpath(1:slashidx(end-1)),instanceinfo(thisInstance).electrodeArtifactRemovedNeuralDataPath{thisElec}(idx:end)];
                            load(fullpath);
                            cmd = ['tmpdata = electrodeArtifactRemovedNeuralData.', MetricsName,';'];
                            eval(cmd);
                        else
                            idx = cell(2,1);
                            idx{1} = find(instanceinfo(thisInstance).electrodeNeuraldataPath{thisElec} == '/');
                            idx{2} = find(instanceinfo(thisInstance).electrodeNeuraldataPath{thisElec} == '\');
                            slash2 = '/\';
                            slash2 = slash2(2-double(isempty(idx{2})));
                            idx = find(instanceinfo(thisInstance).electrodeNeuraldataPath{thisElec} == slash2,1,'first');
                            fullpath = [filefullpath(1:slashidx(end-1)),instanceinfo(thisInstance).electrodeNeuraldataPath{thisElec}(idx:end)];
                            load(fullpath);
                            cmd = ['tmpdata = electrodeNeuraldataPath.', MetricsName,';'];
                            eval(cmd);
                        end
                        for thisCondition = 1:numel(conditions)
                            TrialIdx = instanceinfo(thisInstance).trialInfo.performance ~= 0 & instanceinfo(thisInstance).trialInfo.ConditionID == conditions(thisCondition);
                            if thisMetrics == 1 || thisMetrics == 3 % normalization
                                tmp = tmpdata.data(:,TrialIdx);
                                if thisMetrics == 1 % remove the impact response of MUAe by removing the first 3 data points and replace them with the next 3
                                    tmp(1:3,:) =  tmp(6:-1:4,:);
                                end
                                % normalize the data by spontaneous response
                                idx = xdata < 0;
                                for thisTrial = 1:sum(TrialIdx)
                                    normalizationFactor = var(tmp(idx,thisTrial));
                                    tmp(:,thisTrial) = tmp(:,thisTrial) - mean(tmp(idx,thisTrial));
                                    if normalizationFactor ~= 0
                                        tmp(:,thisTrial) = tmp(:,thisTrial) / normalizationFactor;
                                    end
                                end
            %                                 ydata(:,thisCondition,thisElec,thisInstance) = tmp;
                                ydata{thisCondition}(:,:,thisElec,thisInstance) = tmp;
                            else
            %                                 ydata(:,thisCondition,thisElec,thisInstance) = mean(tmpdata.data(:,TrialIdx),2);
                                ydata{thisCondition}(:,:,thisElec,thisInstance) = tmpdata.data(:,TrialIdx);
                            end
                        end
                    end
                end
                cmd = ['data.', MetricsName, '.xdata = xdata',';'];
                eval(cmd);
                cmd = ['data.', MetricsName, '.ydata = ydata',';'];
                eval(cmd);
            end
            guidata(app.UIFigure,data)
            
            % set time window sliders
            app.TW1Slider.Limits = [trialParam.startT, trialParam.endT];
            app.TW1Slider.Value = 0;
            app.TW2Slider.Limits = [trialParam.startT, trialParam.endT];
            app.TW2Slider.Value = 0.3;
            
        end

        % Button pushed function: UpdateplotButton
        function UpdateplotButtonPushed(app, event)
            
            % plot result
            %             global data;
            data = guidata(app.UIFigure);
            MetricsName = app.MetricsDropDown.Value;
            
            cmd = ['xdata = data.', MetricsName, '.xdata;'];
            eval(cmd);
            cmd = ['ydata = data.', MetricsName, '.ydata;'];
            eval(cmd);
            
            % instances
            cmd = ['instances = ', app.InstancesEditField.Value,';'];
            eval(cmd);
            
            % electrodes
            cmd = ['electrodes = ', app.ElectrodesEditField.Value,';'];
            eval(cmd);
            
            % conditions
            cmd = ['conditions = ', app.ConditionsEditField.Value,';'];
            eval(cmd);
            
            % get conditions
            tmpdata = ydata(conditions);
            
            % average over trials
            ydata = zeros(size(tmpdata{1},1),numel(conditions));
            
            % average over selected instance and electrodes
            for thisCondition = 1:numel(conditions)
            %                 CondID = conditions(thisCondition);
                ydata(:,thisCondition) = squeeze(mean(mean(mean(tmpdata{thisCondition}(:,:,electrodes,instances),4),3),2));
            end
            
            % smooth if data is MUAe or PSTH
            if strcmp(MetricsName,'MUAe') || strcmp(MetricsName,'PSTH')
                for i = 1:size(ydata,2)
                    ydata(:,i) = smooth(ydata(:,i),20,'lowess');
                end
            end
            
            % plot data
            hold(app.UIAxes,'off')
            plot(app.UIAxes,xdata,ydata);
            hold(app.UIAxes,'on')
            % updata legend
            legend(app.UIAxes,'Location','northwest')
            % set axis limit
            ymin = min(min(ydata));
            ymax = max(max(ydata));
            ylim(app.UIAxes,[ymin - 0.1*(ymax-ymin), ymax + 0.1*(ymax-ymin)])
            % plot time window boundries
            if strcmp(event.EventName,'ValueChanging')
                if app.TW1Slider.Value == event.Source.Value
                    TW1 = event.Value;
                    TW2 = app.TW2Slider.Value;
                else
                    TW1 = app.TW1Slider.Value;
                    TW2 = event.Value;
                end
            else
                TW1 = app.TW1Slider.Value;
                TW2 = app.TW2Slider.Value;
            end
            colors = lines(2);
            plot(app.UIAxes,[TW1 TW1], [ymin - 0.1*(ymax-ymin), ymax + 0.1*(ymax-ymin)], 'color',colors(1,:));
            plot(app.UIAxes,[TW2 TW2], [ymin - 0.1*(ymax-ymin), ymax + 0.1*(ymax-ymin)], 'color',colors(2,:));
            
            % Set title
            title(app.UIAxes,MetricsName)
            % Set xlabel
            xlabel(app.UIAxes,'Time from stimulus onset (s)')
            
            % Set ylabel
            if strcmp(MetricsName,'MUAe') || strcmp(MetricsName,'PSTH')
                ylabel(app.UIAxes,'Normalized response')
            else
                ylabel(app.UIAxes,'LFP unit')
            end
            
            % calculate data in time window
            %             cmd = ['tmpdata = data.', MetricsName, '.ydata;'];
            %             eval(cmd);
            
            % time window
            idx = xdata >= TW1 & xdata < TW2;
            
            % average over electrodes and instances
            ydata = cell(numel(conditions),1);
            yavg = zeros(numel(conditions),1);
            ysem = zeros(numel(conditions),1);
            NumTrials = zeros(numel(conditions),1);
            
            % average over selected instance and electrodes
            for thisCondition = 1:numel(conditions)
                ydata{thisCondition} = squeeze(mean(mean(mean(tmpdata{thisCondition}(idx,:,electrodes,instances),4),3),1));
                yavg(thisCondition) = mean(ydata{thisCondition});
                ysem(thisCondition) = std(ydata{thisCondition}) / sqrt(numel(ydata{thisCondition})-1);
                NumTrials(thisCondition) = numel(ydata{thisCondition});
            end
            
            % plot response level within time window
            hold(app.UIAxes2,'off')
            errorbar(app.UIAxes2,1:numel(conditions),yavg,ysem,'color',colors(1,:))
            hold(app.UIAxes2,'on')
            plot(app.UIAxes2,1:numel(conditions),yavg,'.','color',colors(1,:))
            
            % print number of trials
            for thisCondition = 1:numel(conditions)
                text(app.UIAxes2, thisCondition,yavg(thisCondition) + ysem(thisCondition),num2str(NumTrials(thisCondition)));
            end
            
            % set xlabel
            xlabel(app.UIAxes2,'data')
            % set ylabel
            ylabel(app.UIAxes2,'Average response')
            
            % set xlimit
            xlim(app.UIAxes2,[1,(numel(conditions)+1)] - 0.5)
            
            % set title
            title(app.UIAxes2,'Response in time window')
        end

        % Value changing function: TW1Slider
        function TW1SliderValueChanging(app, event)
            %             changingValue = event.Value;
            UpdateplotButtonPushed(app, event);
        end

        % Value changing function: TW2Slider
        function TW2SliderValueChanging(app, event)
            %             changingValue = event.Value;
            UpdateplotButtonPushed(app, event);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.YscaleSliderLabel = uilabel(app.UIFigure);
            app.YscaleSliderLabel.Text = 'Yscale';
            app.YscaleSlider = uislider(app.UIFigure);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.ElectrodesLabel = uilabel(app.Panel);
            app.ElectrodesLabel.Text = 'Electrodes:';
            app.ElectrodesEditField = uieditfield(app.Panel, 'text');
            app.InstancesEditFieldLabel = uilabel(app.Panel);
            app.InstancesEditFieldLabel.Text = 'Instances:';
            app.InstancesEditField = uieditfield(app.Panel, 'text');
            app.MetricsDropDownLabel = uilabel(app.Panel);
            app.MetricsDropDownLabel.Text = 'Metrics:';
            app.MetricsDropDown = uidropdown(app.Panel);
            app.MetricsDropDown.Items = {'MUAe', 'LFP', 'PSTH'};
            app.MetricsDropDown.Value = 'MUAe';
            app.ConditionsEditFieldLabel = uilabel(app.Panel);
            app.ConditionsEditFieldLabel.Text = 'Conditions:';
            app.ConditionsEditField = uieditfield(app.Panel, 'text');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title');
            xlabel(app.UIAxes2, 'X');
            ylabel(app.UIAxes2, 'Y');
            app.TW1SliderLabel = uilabel(app.UIFigure);
            app.TW1SliderLabel.Text = 'TW1';
            app.TW1Slider = uislider(app.UIFigure);
            app.TW1Slider.ValueChangingFcn = createCallbackFcn(app, @TW1SliderValueChanging, true);
            app.TW2SliderLabel = uilabel(app.UIFigure);
            app.TW2SliderLabel.Text = 'TW2';
            app.TW2Slider = uislider(app.UIFigure);
            app.TW2Slider.ValueChangingFcn = createCallbackFcn(app, @TW2SliderValueChanging, true);
            app.FilenameLabel = uilabel(app.UIFigure);
            app.FilenameLabel.Text = 'Filename:';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Value = 'f:\';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.UpdateplotButton = uibutton(app.UIFigure, 'push');
            app.UpdateplotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateplotButtonPushed, true);
            app.UpdateplotButton.Text = 'Update plot';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RecordingFileBrowser()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end